classdef bvApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        WholeBrainCheckBox  matlab.ui.control.CheckBox
        PlotButton          matlab.ui.control.Button
        StatusText          matlab.ui.control.Label
        BrainTree           matlab.ui.container.CheckBoxTree
        BrainAxes           matlab.ui.control.UIAxes
    end


    properties (Access = private)
        bvParams % Parameters saved by user, describes data locations
        annotationTree % Tree structure of numeric annotation values
        acronymTree % Tree structure of region acronyms
        fullNameTree % Tree structure of full region names
        annotationTable % Table of annotations and acronyms
        wholeBrainVolume % Stores vertices and faces of the whole brain, loaded from output path
    end

    methods (Access = private)

        function getWholeBrainVolume(app)
            brainVolumePath = fullfile(app.bvParams.DefaultOutputPath,'WholeBrainSurface.mat');
            if exist(brainVolumePath,'file')
                app.wholeBrainVolume = load(brainVolumePath);
            else
                warnMsg = ["Could not find a file called 'WholeBrainSurface.mat' on path",...
                    string(app.bvParams.DefaultOutputPath),"",...
                    "Would you like to compute the surface now?"];
                selection = uiconfirm(app.UIFigure,warnMsg,"Missing brain surface file");
                if selection == "OK"
                    d = uiprogressdlg(app.UIFigure,"Message","Computing brain surface",...
                        "Indeterminate","on");
                    app.wholeBrainVolume = saveWholeBrainVolume_bvApp(app.bvParams);
                    close(d);
                else
                    app.WholeBrainCheckBox.Value = false;
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Load the path info, assuming it is saved in this folder
            appPath = mfilename('fullpath');
            bvPath = fileparts(fileparts(appPath));
            D = dir([bvPath,filesep,'**',filesep,'brainViewer_params.mat']);
            paramPath = fullfile(D.folder,D.name);
            if exist(paramPath,"file")
                app.bvParams = load(paramPath);
                app.StatusText.Text = 'Loading annotation...';
                drawnow;
                [~,app.annotationTable,app.acronymTree,app.annotationTree,app.fullNameTree] = ...
                    loadAllData_bv(app.bvParams,"annotation");
                app.StatusText.Text = 'Populating tree...';
                drawnow
                populateBrainTree(app,app.fullNameTree);
                app.StatusText.Text = 'Select Regions to Plot:';
            else
                errordlg("Could not find 'brainViewer_params' in the brainViewer folder.")
                app.delete;
            end
            app.PlotButton.Enable = true;
            app.WholeBrainCheckBox.Enable = true;


        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            plotWholeBrain = app.WholeBrainCheckBox.Value;
            if isempty(app.wholeBrainVolume) && plotWholeBrain
                getWholeBrainVolume(app)
            end
            if ~isempty(app.wholeBrainVolume)
                brainPlot3D(app.wholeBrainVolume,'Parent',app.BrainAxes);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.BrainAxes = uiaxes(app.GridLayout);
            app.BrainAxes.XTick = [];
            app.BrainAxes.YTick = [];
            app.BrainAxes.ZTick = [];
            app.BrainTree = uitree(app.GridLayout, 'checkbox');
            app.StatusText = uilabel(app.GridLayout);
            app.PlotButton = uibutton(app.GridLayout, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Enable = 'off';
            app.PlotButton.Text = 'Plot';
            app.WholeBrainCheckBox = uicheckbox(app.GridLayout);
            app.WholeBrainCheckBox.Enable = 'off';
            app.WholeBrainCheckBox.Text = 'Whole Brain';
            app.WholeBrainCheckBox.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bvApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end